classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        ImportdataButton  matlab.ui.control.Button
        UIAxes            matlab.ui.control.UIAxes
        rate_label        matlab.ui.control.Label
    end

%by BraveKek

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportdataButton
        function ImportdataButtonPushed(app, event)
        [file,path] = uigetfile('*.mat')
        if file==0
            return
        end
        ECG = load(fullfile(path,file));
        ECG = struct2array(ECG)
        time= [1:1000];
        plot(app.UIAxes,time,ECG)
        grid(app.UIAxes,'on')
        [PA PT]= findpeaks(ECG,'MinPeakHeight',400)
        QRC = diff(PT)
        average_time = mean(QRC)
        disp(average_time)
         rate = 60*length(ECG)/average_time
        app.rate_label.Visible='on'
        app.rate_label.Text= strcat('Heart Rate(BPM): ',int2str(rate))

        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ImportdataButton = uibutton(app.UIFigure, 'push');
            app.ImportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdataButtonPushed, true);
            app.ImportdataButton.Text = 'Import data';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'ECG Signal')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, ' Amplitude')
            app.rate_label = uilabel(app.UIFigure);
            app.rate_label.Visible = 'off';
            app.rate_label.Text = 'Heart Rate (BPM) :    ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end